classdef RNGMultiThreading < matlab.apps.AppBase
    %RNGMULTITHREADING Visualize how MATLAB's RNGs multi-thread
    %   
    %   Use this application to view side-by-side comparisons of how
    %   MATLAB's Random Number Generators (RNGs) scale with the number of
    %   computational threads available for multi-threading. Results can be
    %   exported to the MATLAB workspace for further analysis.
    %   
    %   Copyright 2025 The MathWorks, Inc.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FunctionDropDown            matlab.ui.control.DropDown
        FunctionDropDownLabel       matlab.ui.control.Label
        OversubscribecoresCheckBox  matlab.ui.control.CheckBox
        ExporttoWorkspaceButton     matlab.ui.control.Button
        CPUGeneratorTree            matlab.ui.container.CheckBoxTree
        CPUGeneratorsNode           matlab.ui.container.TreeNode
        TwisterNode                 matlab.ui.container.TreeNode
        SIMDTwisterNode             matlab.ui.container.TreeNode
        CombRecursiveNode           matlab.ui.container.TreeNode
        MultFibonacciNode           matlab.ui.container.TreeNode
        PhiloxNode                  matlab.ui.container.TreeNode
        ThreefryNode                matlab.ui.container.TreeNode
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MaxThreads = feature('NumCores');
        RandFunction = "rand";
        PlottedNames = string.empty(0,1) % List of which CPU generators are currently plotted
        PlottedValues = zeros(0,feature('NumCores')) % Currently plotted values
    end
    
    properties (Constant, Access = private)
        MaxNameLength = 40; % Max CPU name length before truncating.
    end
    
    methods (Access = private)
        
        function plotResults(app)
            % Update the plot based on the current selection
            disable(app);
            cleaner1 = onCleanup(@() enable(app));

            % Make sure we leave maxNumCompThreads where we found it!
            oldCompThreads = maxNumCompThreads;
            cleaner2 = onCleanup(@() maxNumCompThreads(oldCompThreads));

            selectedNames = getSelectedGenerators(app);
            
            % Work out which results to keep
            newResults = nan(numel(selectedNames),app.MaxThreads);
            [~,ia,ib] = intersect(selectedNames,app.PlottedNames,"stable");
            newResults(ia,:) = app.PlottedValues(ib,:);

            % Replace the plotted variables
            if isempty(newResults)
                lines = [];
                cla(app.UIAxes);
                legend(app.UIAxes, "off")
            else
                lines = plot(app.UIAxes, 1:app.MaxThreads, newResults', ".-", "LineWidth", 2, "MarkerSize", 20);
                legend(app.UIAxes, selectedNames)
            end
            app.UIAxes.XLim = [1 app.MaxThreads];
            app.UIAxes.YLim(1) = 0;
            grid(app.UIAxes, "on")
            drawnow

            % Work out which new results we need to compute.
            missingResults = find(any(isnan(newResults), 2));
            if app.RandFunction == "randn"
                randFcn = @randn;
            else
                randFcn = @rand;
            end

            for ii=1:numel(missingResults)
                thisResult = missingResults(ii);
                rs = RandStream(selectedNames(thisResult));
                N = 5e6;
                % Do a quick warm-up to make sure any startup time is
                % excluded.
                [~] = randFcn(rs,N,1);
                % Now time as the number of threads is varied.
                for jj=1:app.MaxThreads
                    maxNumCompThreads(jj);
                    elapsedTime = timeit(@() randFcn(rs,N,1));
                    newResults(thisResult,jj) = N./elapsedTime;
                    lines(thisResult).YData = newResults(thisResult,:); %#ok<AGROW>
                    % Make sure the plot starts at y=0
                    app.UIAxes.YLimMode = "auto";
                    app.UIAxes.YLim(1) = 0;
                    drawnow
                end
            end
            app.PlottedNames = selectedNames;
            app.PlottedValues = newResults;
            title(app.UIAxes, "Using "+app.cpuname());
        end
        
        function names = getSelectedGenerators(app)
            % Scrape the generator selection tree to see what is selected.
            nodes = app.CPUGeneratorTree.CheckedNodes;
            nodes = nodes(arrayfun(@(x) isempty(x.Children), nodes));
            if isempty(nodes)
                names = string.empty(0,1);
            else
                names = string({nodes.Text});
                names = erase(names, " (default)");
            end
        end
        
        function disable(app)
            % Disable all input controls
            app.CPUGeneratorTree.Enable = "off";
            app.OversubscribecoresCheckBox.Enable = "off";
            app.FunctionDropDown.Enable = "off";
            app.ExporttoWorkspaceButton.Enable = "off";
        end

        function enable(app)
            % Enable input controls as appropriate.
            app.CPUGeneratorTree.Enable = "on";
            app.OversubscribecoresCheckBox.Enable = "on";
            app.FunctionDropDown.Enable = "on";
            % Export button is only enabled if there's something to export.
            if ~isempty(app.PlottedValues)
                app.ExporttoWorkspaceButton.Enable = "on";
            end
        end
        
        function name = cpuname(app)
            % Try to determine the name of the CPU we are running on.
            if ismac
                [~, infostr] = system( sprintf( "sysctl -a %s", "machdep.cpu" ) );
                name = strtrim(extractAfter(string(infostr), "machdep.cpu.brand_string: "));
            else
                name = string(feature("getcpu"));
            end
            % Make sure one line only and maybe truncate if too long.
            name = splitlines(name);
            if numel(name)>1
                name = name(1);
            end
            if strlength(name)>app.MaxNameLength
                name = eraseBetween(name, app.MaxNameLength, strlength(name));
                name = name + "...";
            end

        end
        
        function invalidateResults(app)
            app.PlottedValues = zeros(0,app.MaxThreads);
            app.PlottedNames = string.empty(0,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Change a few things at startup based on the user's
            % environment.

            % Give the window a name!
            app.UIFigure.Name = "Check RNG multi-threading";

            % Unfold the trees
            app.CPUGeneratorTree.Children(1).expand();

            % Append (default) to the default CPU generator
            default = settings().matlab.general.randomnumbers.DefaultAlgorithm.ActiveValue;
            nodes = app.CPUGeneratorTree.Children.Children;
            idx = find(strcmpi({nodes.Text}, default));
            if isscalar(idx)
                nodes(idx).Text = nodes(idx).Text + " (default)";
            end

            app.plotResults()
        end

        % Callback function: CPUGeneratorTree
        function GeneratorTreeCheckedNodesChanged(app, event)
            % A generator has been added or removed from the list.
            app.plotResults()
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            % Put the plotted data into a table and assign it into the base
            % workspace.
            valcells = num2cell(app.PlottedValues', 1);
            tbl = table((1:app.MaxThreads)', valcells{:}, ...
                VariableNames = ["Threads", app.PlottedNames]);
            assignin("base","rngSpeed",tbl)
        end

        % Value changed function: OversubscribecoresCheckBox
        function OversubscribecoresCheckBoxValueChanged(app, event)
            % Switch the over-subscription on or off
            if app.OversubscribecoresCheckBox.Value
                app.MaxThreads = feature('NumCores')*2;
            else
                app.MaxThreads = feature('NumCores');
            end
            % Invalidate all results and re-calculate
            app.invalidateResults();
            app.plotResults()
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            app.RandFunction = string(app.FunctionDropDown.Value);
            % Invalidate all results and re-calculate
            app.invalidateResults();
            app.plotResults()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Comparing Random Number Generators')
            xlabel(app.UIAxes, 'Number of threads')
            ylabel(app.UIAxes, 'Samples / sec')
            zlabel(app.UIAxes, 'Z')
            app.CPUGeneratorTree = uitree(app.UIFigure, 'checkbox');
            app.CPUGeneratorTree.Tooltip = {'Select which random number generators to include in the comparison. Click "CPU Generators" to select or deselect all.'};
            app.CPUGeneratorsNode = uitreenode(app.CPUGeneratorTree);
            app.CPUGeneratorsNode.Text = 'CPU Generators';
            app.TwisterNode = uitreenode(app.CPUGeneratorsNode);
            app.TwisterNode.Text = 'Twister';
            app.SIMDTwisterNode = uitreenode(app.CPUGeneratorsNode);
            app.SIMDTwisterNode.Text = 'SIMDTwister';
            app.CombRecursiveNode = uitreenode(app.CPUGeneratorsNode);
            app.CombRecursiveNode.Text = 'CombRecursive';
            app.MultFibonacciNode = uitreenode(app.CPUGeneratorsNode);
            app.MultFibonacciNode.Text = 'MultFibonacci';
            app.PhiloxNode = uitreenode(app.CPUGeneratorsNode);
            app.PhiloxNode.Text = 'Philox';
            app.ThreefryNode = uitreenode(app.CPUGeneratorsNode);
            app.ThreefryNode.Text = 'Threefry';
            % Assign Checked Nodes
            app.CPUGeneratorTree.CheckedNodesChangedFcn = createCallbackFcn(app, @GeneratorTreeCheckedNodesChanged, true);
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Tooltip = {'Export the plotted data as a table in the MATLAB workspace (called rngSpeed).'};
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.OversubscribecoresCheckBox = uicheckbox(app.UIFigure);
            app.OversubscribecoresCheckBox.ValueChangedFcn = createCallbackFcn(app, @OversubscribecoresCheckBoxValueChanged, true);
            app.OversubscribecoresCheckBox.Tooltip = {'If selected, results will be collected up to 2x the number of physical CPU cores. If not results are collected up to the number of physical cores.'};
            app.OversubscribecoresCheckBox.Text = 'Over-subscribe cores';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'rand', 'randn'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Tooltip = {'Choose between uniformly distributed (RAND) or normally distributed (RANDN) sampling'};
            app.FunctionDropDown.Value = 'rand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RNGMultiThreading

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end